classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TimelapseMode                   matlab.ui.container.ButtonGroup
        baseonChangeMapButton           matlab.ui.control.RadioButton
        IdealButton                     matlab.ui.control.RadioButton
        ChangeratestatisticLabel        matlab.ui.control.Label
        TimelapseGIFLabel               matlab.ui.control.Label
        TimeDropDownLabel               matlab.ui.control.Label
        TimeDropDown                    matlab.ui.control.DropDown
        DifferencehighlightmapareasofchangesareshowninredLabel  matlab.ui.control.Label
        Image1Label                     matlab.ui.control.Label
        AlignedImage2Label              matlab.ui.control.Label
        LargelittlechangeButtonGroup    matlab.ui.container.ButtonGroup
        largeandlittleButton            matlab.ui.control.RadioButton
        littleButton                    matlab.ui.control.RadioButton
        largeButton                     matlab.ui.control.RadioButton
        Fastx2speedslownormalspeedchangeLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        VisualizationModeDropDownLabel  matlab.ui.control.Label
        VisualizationModeDropDown       matlab.ui.control.DropDown
        DemoimagesDropDownLabel         matlab.ui.control.Label
        DemoimagesDropDown              matlab.ui.control.DropDown
        ImagesinputDropDownLabel        matlab.ui.control.Label
        ImagesinputDropDown             matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        time1Tab                        matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        SelectthefolderdirectoryButton  matlab.ui.control.Button
        ModeofchangeLabel               matlab.ui.control.Label
        SegmentationSpinnerLabel        matlab.ui.control.Label
        SegmentationSpinner             matlab.ui.control.Spinner
        Area1CheckBox                   matlab.ui.control.CheckBox
        Area2CheckBox                   matlab.ui.control.CheckBox
        Area3CheckBox                   matlab.ui.control.CheckBox
        Area4CheckBox                   matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SegmentDropDown                 matlab.ui.control.DropDown
        GIFButton                       matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image_loc % Directory of user selected folder
        large_change % bool for large change
        speed_change % Fast for fast change, Slow for slow change
        nSegment % number of segments
        last_nSegment
        visMode % Visualization mode
        area % Area of interest
        data % cache data 
        newImages % bool to run initialization again if new folder is selected
        newSpeed % bool to determine if new visualization mode is selected
        newSeg % bool to determine if new changeMode is selected
        tempComponent % structure contains temporary app component
        cache % Cache data 
        runtime % Number of runs
        overlap_cell1 % GIF parameter
        diff_cell % GIF parameter
        mask_cell1 % GIF parameter
        overlap_cell2
        mask_cell2
        runed_mode_highlight 
        runed_mode_plot
        runed_mode_timelapse
        TimelapseMode_chose
        runed_mode_timelapse1
        runed_mode_timelapse2
    end
    
    methods (Access = private)
        
        function deleteTemp(app)
            name = fieldnames(app.tempComponent);
            for i = 1:length(name)
                delete(app.tempComponent.(name{i}));
                app.tempComponent=rmfield(app.tempComponent,name{i});
            end
        end
        
        function idx = findTimeDropDownIndex(app,value)
            for i = 1:length(app.TimeDropDown.Items)
                if strcmp(value,app.TimeDropDown.Items{i})
                    idx = i;
                end
            end
        end
        
        function [pca_changeMap,changeMap] = findChangeMap(~,I1,alignedI2)
            diff = detectChanges(im2double(rgb2gray(alignedI2)),im2double(rgb2gray(I1))); % Image differencing
            diff = imgaussfilt(diff,1.5); % low pass filter
            pca_changeMap= pcaKmeans(diff,2);
            changeMap = largeLittleChange(pca_changeMap);
        end
        
        function H = findH(app,speed,iter)
            if speed == 1
                H = app.data.H{iter};
            else
                H = projective2d(app.data.H{speed*iter-1}.T*app.data.H{speed*iter}.T);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            app.VisualizationModeDropDown.Value = 'Difference highlight';
            app.speed_change = app.DropDown.Value;
            app.nSegment = app.SegmentationSpinner.Value;
            app.last_nSegment = 1;
            app.image_loc = [pwd filesep 'Demo_images' filesep app.DemoimagesDropDown.Value];
            app.visMode = app.VisualizationModeDropDown.Value;
            app.newImages = 1;
            app.newSpeed = 1;
            app.newSeg = 1;
            app.tempComponent = struct;
            app.area = 'All';
            app.cache = struct();
            app.runtime = 1;
            app.large_change = 2;
            app.runed_mode_highlight = 0;
            app.runed_mode_plot = 0;
            app.runed_mode_timelapse = 0;
            app.TimelapseMode_chose = 0;
            app.runed_mode_timelapse1 = 0;
            app.runed_mode_timelapse2 = 0;
            mkdir GIF;
            
        end

        % Value changed function: ImagesinputDropDown
        function ImagesinputDropDownValueChanged(app, event)
            value = app.ImagesinputDropDown.Value;
            if strcmp(value,'Demo images')
                app.SelectthefolderdirectoryButton.Visible = 'off';
                app.SelectthefolderdirectoryButton.Enable = 'off';
                app.DemoimagesDropDownLabel.Visible = 'on';
                app.DemoimagesDropDown.Visible =  'on';
                app.DemoimagesDropDown.Enable = 'on';
                app.Label.Visible = 'off';
                app.image_loc = [pwd filesep 'Demo_images' filesep app.DemoimagesDropDown.Value];
                app.newImages = 1;
            else
                app.DemoimagesDropDownLabel.Visible = 'off';
                app.DemoimagesDropDown.Visible =  'off';
                app.DemoimagesDropDown.Enable = 'off';
                app.SelectthefolderdirectoryButton.Visible = 'on';
                app.SelectthefolderdirectoryButton.Enable = 'on';
                app.Label.Visible = 'on';
                app.Label.Text = 'No folder is selected.';
                app.image_loc  = '';
                app.newImages = 1;
            end
        end

        % Button pushed function: SelectthefolderdirectoryButton
        function SelectthefolderdirectoryButtonPushed(app, event)
            loc = uigetdir;
            figure(app.UIFigure);
            app.Label.Visible = 'on';

            if loc ~= 0
                app.image_loc = loc;
                [~,name,~] = fileparts(app.image_loc);
                app.Label.Text = ['Location:' name];
                app.newImages = 1;
                app.runed_mode_highlight = 0;
                app.runed_mode_plot = 0;
                app.runed_mode_timelapse = 0;
                app.runed_mode_timelapse1 = 0;
                app.runed_mode_timelapse2 = 0;
            end
        end

        % Value changed function: SegmentationSpinner
        function SegmentationSpinnerValueChanged(app, event)
            value = app.SegmentationSpinner.Value;
            app.nSegment = value;
%             if first_run_mode2 &&
%                 app.last_nSegment = app.nSegment;
%             end 
            text = {'All','Area 1','Area 2','Area 3','Area 4'};
            app.SegmentDropDown.Items = text(1:value+1);
            app.SegmentDropDown.Enable = 'on';
            drawnow;
%             for i = 1:4
%                 nodeName = ['Area' num2str(i) 'CheckBox'];
%                 if i <= value
%                     app.(nodeName).Visible = 'on';
%                     app.(nodeName).Enable = 'on';
%                     app.(nodeName).Value = 1;
%                 else
%                     app.(nodeName).Visible = 'off';
%                     app.(nodeName).Enable = 'off';
%                 end
%             end
            if value == 1
%                 app.Area1CheckBox.Value = 1;
%                 app.Area1CheckBox.Enable = 'Off';
                app.SegmentDropDown.Value = text{1};
                app.SegmentDropDown.Enable = 'off';
            end
            app.area = app.SegmentDropDown.Value;
            app.newSeg = 1;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.speed_change = value;
            app.newSpeed = 1;
        end

        % Value changed function: VisualizationModeDropDown
        function VisualizationModeDropDownValueChanged(app, event)
            value = app.VisualizationModeDropDown.Value;
            app.visMode = value;
            if strcmp(value,'Difference highlight')
                app.AlignedImage2Label.Visible = 'on';
                app.Image1Label.Visible = 'on';
                app.TabGroup.Visible = 'on';
                app.DifferencehighlightmapareasofchangesareshowninredLabel.Visible = 'on';
                app.TimeDropDown.Visible = 'on';
                app.TimeDropDown.Enable = 'on';
                app.TimeDropDownLabel.Visible = 'on';
                app.TimelapseGIFLabel.Visible = 'off';
                app.GIFButton.Visible = 'off';
                app.ChangeratestatisticLabel.Visible = 'off';
                app.LargelittlechangeButtonGroup.Enable = 'on';
                app.ModeofchangeLabel.Enable = 'on';
                app.DropDown.Enable = 'on';
                app.SegmentationSpinner.Enable = 'on';
                app.UIAxes4.Visible = 'off';
                app.TimelapseMode.Visible = 'off';
            elseif strcmp(value,'Timelapse')
                app.AlignedImage2Label.Visible = 'off';
                app.Image1Label.Visible = 'off';
                app.TabGroup.Visible = 'off';
                app.DifferencehighlightmapareasofchangesareshowninredLabel.Visible = 'off';
                app.TimeDropDown.Visible = 'off';
                app.TimeDropDown.Enable = 'off';
                app.TimeDropDownLabel.Visible = 'off';
                app.TimelapseGIFLabel.Visible = 'on';
                app.GIFButton.Visible = 'on';
                app.ChangeratestatisticLabel.Visible = 'off';
                app.UIAxes4.Visible = 'off';
                app.LargelittlechangeButtonGroup.Enable = 'on';
                app.ModeofchangeLabel.Enable = 'on';
                app.DropDown.Enable = 'on';
                app.SegmentationSpinner.Enable = 'on';
                app.TimelapseMode.Visible = 'on';
                app.TimelapseMode.Enable = 'on';
            else
                app.AlignedImage2Label.Visible = 'off';
                app.Image1Label.Visible = 'off';
                app.TabGroup.Visible = 'off';
                app.DifferencehighlightmapareasofchangesareshowninredLabel.Visible = 'off';
                app.TimeDropDown.Visible = 'off';
                app.TimeDropDown.Enable = 'off';
                app.TimeDropDownLabel.Visible = 'off';
                app.TimelapseGIFLabel.Visible = 'off';
                app.GIFButton.Visible = 'off';
                app.ChangeratestatisticLabel.Visible = 'on';
                app.LargelittlechangeButtonGroup.Enable = 'off';
                app.ModeofchangeLabel.Enable = 'off';
                app.DropDown.Enable = 'off';
                app.SegmentationSpinner.Enable = 'off';
                app.UIAxes4.Visible = 'on';
                app.SegmentDropDown.Enable = 'off';
                app.TimelapseMode.Visible = 'off';
            end


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            if strcmp(app.speed_change,'Slow')
                speed = 1;
            else
                speed = 2;
            end
            if isempty(app.image_loc)
                errordlg('No folder is selected!');
                return;
            elseif length(dir(app.image_loc)) < 3+speed
                errordlg('Not enough images in folder. Please make sure at least 2/3 images are in the selected folder for slow/fast change.');
                return;
            end
            app.RunButton.Text = 'Running, please wait';
            app.RunButton.Enable = 'off';
            
            %app.GIFButton.Icon = '';
            drawnow;
            if app.newImages
                app.data = init(app.image_loc);
                app.cache = struct();
            elseif app.newSpeed
                app.cache = struct();
            end
            if strcmp(app.visMode,'Difference highlight')
                app.TimeDropDown.Items = {};
                deleteTemp(app);
                if strcmp(app.speed_change,'Slow')
                    nIter = length(app.data.H);
                    speed = 1;
                else
                    nIter = ceil(length(app.data.Image)/2-1);
                    speed = 2;
                end
                app.time1Tab.Title = [app.data.time{1}(1:4),'.',app.data.time{1}(6:7),' vs ',app.data.time{2}(1:4),'.',app.data.time{2}(6:7)];
                for i = 1:nIter
                    Name = [app.data.time{i}(1:4),'.',app.data.time{i}(6:7),' vs ',app.data.time{i+1}(1:4),'.',app.data.time{i+1}(6:7)];
                    itemName = ['time',num2str(i),'Tab'];
                    axName1 = ['UIAxes1_' num2str(i)];
                    axName2 = ['UIAxes2_' num2str(i)];
                    axName3 = ['UIAxes3_' num2str(i)];
                    app.TimeDropDown.Items = [app.TimeDropDown.Items Name];
                    
%                     changeMap = diffHighlightVis(app.data.Image{i},app.data.changeMap{i});
                    if app.newSpeed  || app.newImages 
                        
                        app.cache.I1{i} = app.data.Image{speed*(i-1)+1};
                        I1 = app.cache.I1{i};
                        app.cache.H{i} = findH(app,speed,i);
                        H = app.cache.H{i};
                        app.cache.alignedI2{i} = alignImage(app.data.Image{speed*(i)+1},I1,H);
                        alignedI2 = app.cache.alignedI2{i};
                        %app.cache.changeMap{i} = findChangeMap(app,I1,alignedI2);
                        [app.cache.pca_changeMap{i},app.cache.changeMap{i}] = findChangeMap(app,I1,alignedI2);
                        changeMap = diffHighlightVis(I1,app.cache.changeMap{i},app.large_change);      
                    elseif app.runed_mode_timelapse && ~app.runed_mode_plot && ~app.runed_mode_highlight
                        app.cache.I1{i} = app.data.Image{speed*(i-1)+1};
                        I1 = app.cache.I1{i};
                        app.cache.H{i} = findH(app,speed,i);
                        H = app.cache.H{i};
                        app.cache.alignedI2{i} = alignImage(app.data.Image{speed*(i)+1},I1,H);
                        alignedI2 = app.cache.alignedI2{i};
                        %app.cache.changeMap{i} = findChangeMap(app,I1,alignedI2);
                        [app.cache.pca_changeMap{i},app.cache.changeMap{i}] = findChangeMap(app,I1,alignedI2);
                        changeMap = diffHighlightVis(I1,app.cache.changeMap{i},app.large_change);
                    else
                        %app.cache.I1{i} = app.data.Image{speed*(i-1)+1};
                        I1 = app.cache.I1{i};
                        %app.cache.H{i} = findH(app,speed,i);
                        %H = app.cache.H{i};
                        %app.cache.alignedI2{i} = alignImage(app.data.Image{speed*(i)+1},I1,H);
                        alignedI2 = app.cache.alignedI2{i};
                        changeMap = diffHighlightVis(I1,app.cache.changeMap{i},app.large_change);
                    end
                    
                    if app.nSegment > 1
                        if app.newSeg || app.newImages || app.newSpeed
                            %changeMap = im2uint8(changeMap);
                            pca_changeMap = im2uint8(app.cache.pca_changeMap{i});
                            [app.cache.Im1_cell.(['c' num2str(i)]),app.cache.Im_changeMapSeg.(['c' num2str(i)]),~,app.cache.Im2_cell.(['c' num2str(i)])] = ...
                                changeMapSeg(I1,pca_changeMap,app.nSegment,alignedI2);
                        end
                        if ~strcmp('All',app.area)
                            Im_changeMapSeg = app.cache.Im_changeMapSeg.(['c' num2str(i)]);
                            Im1_cell = app.cache.Im1_cell.(['c' num2str(i)]);
                            Im2_cell =app.cache.Im2_cell.(['c' num2str(i)]);
                            if strcmp(app.area,'Area 1')
                                idx = 1;
                            elseif strcmp(app.area,'Area 2')
                                idx = 2;
                            elseif strcmp(app.area,'Area 3')
                                idx = 3;
                            elseif strcmp(app.area,'Area 4')
                                idx = 4;
                            end
                            changeMap = Im_changeMapSeg{idx};
                            I1 = Im1_cell{idx};
                            alignedI2 = Im2_cell{idx};
                        end
                    end
                    if i >= 2
                        app.tempComponent.(itemName) = uitab(app.TabGroup);
                        app.tempComponent.(itemName).Title = Name;
                        app.TabGroup.SelectedTab = app.tempComponent.(itemName);
                        app.TimeDropDown.Value = Name;
                        
                        app.tempComponent.(axName1) = uiaxes(app.tempComponent.(itemName),"Visible","off","Position",[63,449,295,206]);
                        app.tempComponent.(axName2) = uiaxes(app.tempComponent.(itemName),"Visible","off","Position",[452,449,295,206]);
                        app.tempComponent.(axName3) = uiaxes(app.tempComponent.(itemName),"Visible","off","Position",[21,1,788,415]);
                        imshow(I1,'Parent',app.tempComponent.(axName1));
                        imshow(alignedI2,'Parent',app.tempComponent.(axName2));
                        imshow(changeMap,'Parent',app.tempComponent.(axName3));
                    else
                        imshow(I1,'Parent',app.UIAxes1);
                        imshow(alignedI2,'Parent',app.UIAxes2);
                        imshow(changeMap,'Parent',app.UIAxes3);
                    end        
                    pause(1);
                end
                app.runed_mode_highlight = 1;
            elseif strcmp(app.visMode,'Timelapse')
                
                if strcmp(app.area,'Area 1')
                    idx = 1;
                elseif strcmp(app.area,'Area 2')
                    idx = 2;
                elseif strcmp(app.area,'Area 3')
                    idx = 3;
                elseif strcmp(app.area,'Area 4')
                    idx = 4;
                else 
                    idx = 0;
                end
                image_loc_2 = length(app.data.H) + 3;
                if app.TimelapseMode_chose
                    if app.newSpeed || app.newImages || ~app.runed_mode_timelapse1
                        [app.diff_cell,app.overlap_cell1, app.mask_cell1] = gif_prepare(app.data,app.image_loc, app.speed_change);
                        app.runed_mode_timelapse1 = 1;
                    end
                    gif_generate(app.nSegment,idx,speed,app.overlap_cell1,app.diff_cell, app.mask_cell1,image_loc_2,app.runtime);
                    %on_ChangeMap = app.overlap_cell
                else
                    if app.newSpeed || app.newImages || ~app.runed_mode_timelapse2
                        [app.overlap_cell2, app.mask_cell2] = gif_prepare_dummy(app.data,app.image_loc, app.speed_change);
                        app.runed_mode_timelapse2 = 1;
                    end
                    %on_H = app.overlap_cell
                    gif_generate_dummy(speed,app.nSegment,idx,app.overlap_cell2,app.mask_cell2,image_loc_2,app.runtime);
                end
               pause(0.1);
               
               addpath (genpath(pwd));
               app.GIFButton.Icon = [num2str(app.runtime)  '.gif'];
              % app.runed_mode_timelapse = max(app.runed_mode_timelapse1,app.runed_mode_timelapse2);
                app.runed_mode_timelapse = 1;
            else
                nIter = length(app.data.H);
                %x = cell{nIter};
                if  app.runed_mode_highlight && ~app.newImages && ~app.newImages
                    for i = 1:nIter
                        changeMap = app.cache.changeMap{i};
                        app.cache.changeRATE(i) = changeRate(changeMap);
                        x{i} = [app.data.time{i}(1:4) '/',app.data.time{i+1}(1:4)];
                    end           
                else  
                     for i = 1:nIter
                        I1 = app.data.Image{i};
                        app.cache.I1{i} = app.data.Image{i};
                        alignedI2 = alignImage(app.data.Image{i+1},I1,app.data.H{i});
                        app.cache.alignedI2{i} = alignedI2;
                        diff = detectChanges(im2double(rgb2gray(alignedI2)),im2double(rgb2gray(I1))); % Image differencing
                        diff = imgaussfilt(diff,1.5); % low pass filter
                        changeMap = pcaKmeans(diff,2);
                        app.cache.changeMap{i} = changeMap;
                        app.cache.changeRATE(i) = changeRate(changeMap);
                        x{i} = [app.data.time{i}(1:4) '/',app.data.time{i+1}(1:4)];
                     end
                    
                end
                X = categorical(x);
                X = reordercats(X,x);
                b = bar(app.UIAxes4,X,app.cache.changeRATE);
                xtips = b.XEndPoints;
                ytips = b.YEndPoints;       
                labels = strcat(string(b.YData),'%');
                text(app.UIAxes4,xtips,ytips,labels,'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom');
%                 app.UIAxes4.YTick = [0 0.5 1];
%                 app.UIAxes4.YTickLabel = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
                app.runed_mode_plot  = 1;
            end
            app.RunButton.Text = 'Run';
            app.RunButton.Enable = 'on';
            app.newImages = 0;
            app.newSpeed = 0;
            app.newSeg = 0;
            app.runtime = app.runtime + 1;
            app.last_nSegment = app.nSegment;
        end

        % Value changed function: DemoimagesDropDown
        function DemoimagesDropDownValueChanged(app, event)
            value = app.DemoimagesDropDown.Value;
            app.image_loc = [pwd filesep 'Demo_images' filesep value];
            app.newImages = 1;
            app.runed_mode_highlight = 0;
            app.runed_mode_plot = 0;
            app.runed_mode_timelapse = 0;
            app.runed_mode_timelapse1 = 0;
            app.runed_mode_timelapse2 = 0;
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            value = app.TimeDropDown.Value;
            idx = findTimeDropDownIndex(app,value);
            itemName = ['time',num2str(idx),'Tab'];
            if idx ==1
                app.TabGroup.SelectedTab = app.(itemName);
            else
                app.TabGroup.SelectedTab = app.tempComponent.(itemName);
            end
            
        end

        % Value changed function: Area1CheckBox
        function Area1CheckBoxValueChanged(app, event)
            value = app.Area1CheckBox.Value;
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            value = app.SegmentDropDown.Value;
            app.area = value;
        end

        % Selection changed function: LargelittlechangeButtonGroup
        function LargelittlechangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.LargelittlechangeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'large')
                app.large_change = 1;
            elseif strcmp(selectedButton.Text,'little')
                app.large_change = 0;
            else
                app.large_change = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            if exist('GIF','dir')
                rmdir('GIF','s'); 
            end
        end

        % Selection changed function: TimelapseMode
        function TimelapseModeSelectionChanged(app, event)
            selectedButton = app.TimelapseMode.SelectedObject;
            if strcmp(selectedButton.Text,'Ideal')
                app.TimelapseMode_chose = 0;
            else
                app.TimelapseMode_chose = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'Year')
            ylabel(app.UIAxes4, 'Change rate')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [0 0.5 1];
            app.UIAxes4.Visible = 'off';
            app.GIFButton = uibutton(app.UIFigure, 'push');
            app.GIFButton.Visible = 'off';
            app.GIFButton.Text = '';
            app.SegmentDropDown = uidropdown(app.UIFigure);
            app.SegmentDropDown.Items = {'All', 'Area1'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Enable = 'off';
            app.SegmentDropDown.Value = 'All';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Area4CheckBox = uicheckbox(app.UIFigure);
            app.Area4CheckBox.Enable = 'off';
            app.Area4CheckBox.Visible = 'off';
            app.Area4CheckBox.Text = 'Area 4';
            app.Area3CheckBox = uicheckbox(app.UIFigure);
            app.Area3CheckBox.Enable = 'off';
            app.Area3CheckBox.Visible = 'off';
            app.Area3CheckBox.Text = 'Area 3';
            app.Area2CheckBox = uicheckbox(app.UIFigure);
            app.Area2CheckBox.Enable = 'off';
            app.Area2CheckBox.Visible = 'off';
            app.Area2CheckBox.Text = 'Area 2';
            app.Area1CheckBox = uicheckbox(app.UIFigure);
            app.Area1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Area1CheckBoxValueChanged, true);
            app.Area1CheckBox.Enable = 'off';
            app.Area1CheckBox.Visible = 'off';
            app.Area1CheckBox.Text = 'Area 1';
            app.Area1CheckBox.Value = true;
            app.SegmentationSpinner = uispinner(app.UIFigure);
            app.SegmentationSpinner.Limits = [1 4];
            app.SegmentationSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentationSpinnerValueChanged, true);
            app.SegmentationSpinner.Value = 1;
            app.SegmentationSpinnerLabel = uilabel(app.UIFigure);
            app.SegmentationSpinnerLabel.Text = 'Segmentation';
            app.ModeofchangeLabel = uilabel(app.UIFigure);
            app.ModeofchangeLabel.Text = 'Mode of change';
            app.SelectthefolderdirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectthefolderdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectthefolderdirectoryButtonPushed, true);
            app.SelectthefolderdirectoryButton.Enable = 'off';
            app.SelectthefolderdirectoryButton.Visible = 'off';
            app.SelectthefolderdirectoryButton.Text = 'Select the folder directory';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.time1Tab = uitab(app.TabGroup);
            app.time1Tab.Title = 'time1';
            app.UIAxes1 = uiaxes(app.time1Tab);
            app.UIAxes1.Visible = 'off';
            app.UIAxes2 = uiaxes(app.time1Tab);
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.time1Tab);
            app.UIAxes3.Visible = 'off';
            app.ImagesinputDropDown = uidropdown(app.UIFigure);
            app.ImagesinputDropDown.Items = {'Demo images', 'Choose folder'};
            app.ImagesinputDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesinputDropDownValueChanged, true);
            app.ImagesinputDropDown.Tooltip = {''};
            app.ImagesinputDropDown.Value = 'Demo images';
            app.ImagesinputDropDownLabel = uilabel(app.UIFigure);
            app.ImagesinputDropDownLabel.Text = 'Images input';
            app.DemoimagesDropDown = uidropdown(app.UIFigure);
            app.DemoimagesDropDown.Items = {'Brazilian Rainforest', 'Columbia Glacier', 'Dubai'};
            app.DemoimagesDropDown.ValueChangedFcn = createCallbackFcn(app, @DemoimagesDropDownValueChanged, true);
            app.DemoimagesDropDown.Value = 'Brazilian Rainforest';
            app.DemoimagesDropDownLabel = uilabel(app.UIFigure);
            app.DemoimagesDropDownLabel.Text = 'Demo images';
            app.VisualizationModeDropDown = uidropdown(app.UIFigure);
            app.VisualizationModeDropDown.Items = {'Difference highlight', 'Timelapse', 'Change rate'};
            app.VisualizationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationModeDropDownValueChanged, true);
            app.VisualizationModeDropDown.Value = 'Difference highlight';
            app.VisualizationModeDropDownLabel = uilabel(app.UIFigure);
            app.VisualizationModeDropDownLabel.Text = 'Visualization Mode';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Slow', 'Fast'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Slow';
            app.Fastx2speedslownormalspeedchangeLabel = uilabel(app.UIFigure);
            app.Fastx2speedslownormalspeedchangeLabel.Text = 'Fast (x2 speed)/slow (normal speed) change';
            app.LargelittlechangeButtonGroup = uibuttongroup(app.UIFigure);
            app.LargelittlechangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LargelittlechangeButtonGroupSelectionChanged, true);
            app.LargelittlechangeButtonGroup.Title = 'Large/little change';
            app.largeButton = uiradiobutton(app.LargelittlechangeButtonGroup);
            app.largeButton.Text = 'large';
            app.littleButton = uiradiobutton(app.LargelittlechangeButtonGroup);
            app.littleButton.Text = 'little';
            app.largeandlittleButton = uiradiobutton(app.LargelittlechangeButtonGroup);
            app.largeandlittleButton.Text = 'large and little';
            app.largeandlittleButton.Value = true;
            app.AlignedImage2Label = uilabel(app.UIFigure);
            app.AlignedImage2Label.Text = 'Aligned Image 2';
            app.Image1Label = uilabel(app.UIFigure);
            app.Image1Label.Text = 'Image 1';
            app.DifferencehighlightmapareasofchangesareshowninredLabel = uilabel(app.UIFigure);
            app.DifferencehighlightmapareasofchangesareshowninredLabel.Text = 'Difference highlight map: areas of changes are shown in red';
            app.TimeDropDown = uidropdown(app.UIFigure);
            app.TimeDropDown.Items = {};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = {};
            app.TimeDropDownLabel = uilabel(app.UIFigure);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimelapseGIFLabel = uilabel(app.UIFigure);
            app.TimelapseGIFLabel.Visible = 'off';
            app.TimelapseGIFLabel.Text = 'Timelapse GIF';
            app.ChangeratestatisticLabel = uilabel(app.UIFigure);
            app.ChangeratestatisticLabel.Visible = 'off';
            app.ChangeratestatisticLabel.Text = 'Change rate statistic';
            app.TimelapseMode = uibuttongroup(app.UIFigure);
            app.TimelapseMode.SelectionChangedFcn = createCallbackFcn(app, @TimelapseModeSelectionChanged, true);
            app.TimelapseMode.Enable = 'off';
            app.TimelapseMode.Visible = 'off';
            app.IdealButton = uiradiobutton(app.TimelapseMode);
            app.IdealButton.Text = 'Ideal';
            app.IdealButton.Value = true;
            app.baseonChangeMapButton = uiradiobutton(app.TimelapseMode);
            app.baseonChangeMapButton.Text = 'base on ChangeMap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end